classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SwitchYScale            matlab.ui.control.Switch
        AvaliarDesviodeAllanparaumespecficoLabel_2  matlab.ui.control.Label
        ResultSpecificTauLabel  matlab.ui.control.Label
        OpesLabel               matlab.ui.control.Label
        SpecificTauEditField    matlab.ui.control.NumericEditField
        InformeovalordeTauentre10se100sLabel  matlab.ui.control.Label
        SwitchSpecificTau       matlab.ui.control.Switch
        AvaliarDesviodeAllanparaumespecficoLabel  matlab.ui.control.Label
        ClearButton             matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        DeltaTabEditField       matlab.ui.control.NumericEditField
        TaTbKLabel              matlab.ui.control.Label
        SabEditField            matlab.ui.control.NumericEditField
        S_abVKLabel             matlab.ui.control.Label
        A20GainDropDown         matlab.ui.control.DropDown
        GanhodoA2010Label       matlab.ui.control.Label
        I1nominalDropDown       matlab.ui.control.DropDown
        I1nominalDropDownLabel  matlab.ui.control.Label
        NbDropDown              matlab.ui.control.DropDown
        NbDropDownLabel         matlab.ui.control.Label
        N2DropDown              matlab.ui.control.DropDown
        N2DropDownLabel         matlab.ui.control.Label
        N1DropDown              matlab.ui.control.DropDown
        N1DropDownLabel         matlab.ui.control.Label
        T2CEditField            matlab.ui.control.NumericEditField
        T2CEditFieldLabel       matlab.ui.control.Label
        T1CEditField            matlab.ui.control.NumericEditField
        T1CEditFieldLabel       matlab.ui.control.Label
        R1DropDown              matlab.ui.control.DropDown
        R2DropDown              matlab.ui.control.DropDown
        R2DropDownLabel         matlab.ui.control.Label
        RunButton               matlab.ui.control.Button
        R1DropDownLabel         matlab.ui.control.Label
        ParmetrosdeentradaparaoclculodorudodacorrentedebalancedoCCCLabel  matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % User inputs
            R1 = app.R1DropDown.Value;
            R2 = app.R2DropDown.Value;
            T1 = app.T1CEditField.Value;
            T2 = app.T2CEditField.Value;
            N1 = app.N1DropDown.Value;
            N2 = app.N2DropDown.Value;
            Nb = app.NbDropDown.Value;
            I1 = app.I1nominalDropDown.Value;
            A20_gain = app.A20GainDropDown.Value;
            S_ab = app.SabEditField.Value;
            DeltaT_ab = app.DeltaTabEditField.Value;
            FlagSpecificTau = app.SwitchSpecificTau.Value;
            SpecificTau = app.SpecificTauEditField.Value;
            
            % Sends the parameter values ��to the program
            in_bal_estimator_v3(R1,R2,T1,T2,N1,N2,Nb,I1,A20_gain,S_ab,DeltaT_ab,FlagSpecificTau,SpecificTau);

            % Receives the arrays from the program to plot the graph and the calculated noise (Allan deviation) of the balance current for an specific tau
            [tauArray, In_balArray,AD4SpecificTau] = in_bal_estimator_v3(R1,R2,T1,T2,N1,N2,Nb,I1,A20_gain,S_ab,DeltaT_ab,FlagSpecificTau,SpecificTau);
            
            % Output :: sigma-tau graph (customized configuration)
            plot(app.UIAxes, tauArray, In_balArray, 'Color', [0.4 0 0.4], 'LineWidth', 2);
            app.UIAxes.XScale = 'log';
            app.UIAxes.YScale = 'log';
            xlabel(app.UIAxes, 'Intervalo de medição \tau (s)');
            ylabel(app.UIAxes, 'Desvio de Allan (nA)');
            app.UIAxes.XLim = [10 100];
            if app.SwitchYScale.Value == "Fixa"
                app.UIAxes.YLim = [1 100];
            else
                app.UIAxes.YLimMode = 'auto';
            end
            grid(app.UIAxes, 'on');
                  
            % Output :: noise (Allan deviation) of the balance current for an specific tau
            app.ResultSpecificTauLabel.Text = [num2str(AD4SpecificTau)];
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Reset the graph and the result label that shows the estimated balance current noise (in terms of Allan deviation) for an specific tau
            cla(app.UIAxes);
            app.ResultSpecificTauLabel.Text = "";
            % app.SpecificTauEditField.Value = 10;
        end

        % Value changed function: SwitchYScale
        function YScaleMode(app, event)
            % Toggles the scale mode of the y-axis between fixed and automatic - even after code execution
            if app.SwitchYScale.Value == "Fixa"
                app.UIAxes.YLim = [1 100];
            else
                app.UIAxes.YLimMode = 'auto';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Resultados da simulação')
            xlabel(app.UIAxes, 'Intervalo de medição τ (s)')
            ylabel(app.UIAxes, 'Desvio de Allan (nA)')
            app.UIAxes.XScale = 'log';
            app.UIAxes.XTickLabel = {'10^{1}'; '10^{2}'};
            app.UIAxes.YScale = 'log';
            app.ParmetrosdeentradaparaoclculodorudodacorrentedebalancedoCCCLabel = uilabel(app.UIFigure);
            app.ParmetrosdeentradaparaoclculodorudodacorrentedebalancedoCCCLabel.Tooltip = {'Os parâmetros a seguir são de conhecimento do operador do CCC e permitem estimar o ruído da corrente de balance'};
            app.ParmetrosdeentradaparaoclculodorudodacorrentedebalancedoCCCLabel.Text = 'Parâmetros de entrada para o cálculo do ruído da corrente de balance do CCC';
            app.R1DropDownLabel = uilabel(app.UIFigure);
            app.R1DropDownLabel.Tooltip = {''};
            app.R1DropDownLabel.Text = 'R1 (Ω)';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'RODAR';
            app.R2DropDownLabel = uilabel(app.UIFigure);
            app.R2DropDownLabel.Tooltip = {'R2 é o resistor conectado ao braço slave do CCC'};
            app.R2DropDownLabel.Text = 'R2 (Ω)';
            app.R2DropDown = uidropdown(app.UIFigure);
            app.R2DropDown.Items = {'1', '10', '100', '1000', '10000', '12906.4037297'};
            app.R2DropDown.Tooltip = {'12906.4037297 corresponde à resistência Hall quantizada (QHR) no platô i = 2'};
            app.R2DropDown.Value = '100';
            app.R1DropDown = uidropdown(app.UIFigure);
            app.R1DropDown.Items = {'1', '10', '100', '1000', '10000'};
            app.R1DropDown.Tooltip = {'R1 é o resistor conectado ao braço master do CCC'};
            app.R1DropDown.Value = '100';
            app.T1CEditFieldLabel = uilabel(app.UIFigure);
            app.T1CEditFieldLabel.Tooltip = {'Temperatura de R1'};
            app.T1CEditFieldLabel.Text = 'T1 (°C)';
            app.T1CEditField = uieditfield(app.UIFigure, 'numeric');
            app.T1CEditField.Tooltip = {''};
            app.T1CEditField.Value = 23;
            app.T2CEditFieldLabel = uilabel(app.UIFigure);
            app.T2CEditFieldLabel.Tooltip = {'Temperatura de R2'};
            app.T2CEditFieldLabel.Text = 'T2 (°C)';
            app.T2CEditField = uieditfield(app.UIFigure, 'numeric');
            app.T2CEditField.Tooltip = {'Na resistência Hall quantizada (QHR), T2 é 0 K (-273,15 ºC)'};
            app.T2CEditField.Value = 23;
            app.N1DropDownLabel = uilabel(app.UIFigure);
            app.N1DropDownLabel.Text = 'N1';
            app.N1DropDown = uidropdown(app.UIFigure);
            app.N1DropDown.Items = {'1', '2', '4', '8', '16', '32', '80', '320'};
            app.N1DropDown.Tooltip = {'Número de voltas da bobina do braço master do CCC'};
            app.N1DropDown.Value = '32';
            app.N2DropDownLabel = uilabel(app.UIFigure);
            app.N2DropDownLabel.Text = 'N2';
            app.N2DropDown = uidropdown(app.UIFigure);
            app.N2DropDown.Items = {'1', '2', '4', '8', '16', '32', '80', '320', '800', '1600', '2065'};
            app.N2DropDown.Tooltip = {'Número de voltas da bobina do braço slave do CCC'};
            app.N2DropDown.Value = '32';
            app.NbDropDownLabel = uilabel(app.UIFigure);
            app.NbDropDownLabel.Text = 'Nb';
            app.NbDropDown = uidropdown(app.UIFigure);
            app.NbDropDown.Items = {'1', '2'};
            app.NbDropDown.Tooltip = {'Número de voltas da bobina de balance do CCC'};
            app.NbDropDown.Value = '1';
            app.I1nominalDropDownLabel = uilabel(app.UIFigure);
            app.I1nominalDropDownLabel.Text = 'I1 nominal';
            app.I1nominalDropDown = uidropdown(app.UIFigure);
            app.I1nominalDropDown.Items = {'3 mA', '10 mA', '30 mA'};
            app.I1nominalDropDown.Tooltip = {'Valor nominal da corrente aplicada ao braço master do CCC'};
            app.I1nominalDropDown.Value = '3 mA';
            app.GanhodoA2010Label = uilabel(app.UIFigure);
            app.GanhodoA2010Label.Text = 'Ganho do A20: 10^';
            app.A20GainDropDown = uidropdown(app.UIFigure);
            app.A20GainDropDown.Items = {'3', '4'};
            app.A20GainDropDown.Tooltip = {'Ganho do detector de nulo (A20), na forma 10^(x)'};
            app.A20GainDropDown.Value = '3';
            app.S_abVKLabel = uilabel(app.UIFigure);
            app.S_abVKLabel.Text = 'S_ab (μV/K)';
            app.SabEditField = uieditfield(app.UIFigure, 'numeric');
            app.SabEditField.Tooltip = {'Coeficiente de Seebeck do material “a” e relação ao material “b” nas conexões'};
            app.SabEditField.Value = 0.2;
            app.TaTbKLabel = uilabel(app.UIFigure);
            app.TaTbKLabel.Text = 'Ta - Tb (K)';
            app.DeltaTabEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeltaTabEditField.Tooltip = {'Diferença de temperatura entre os materiais “a” e “b” das conexões'};
            app.DeltaTabEditField.Value = 0.001;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'inmetro.jpg');
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Tooltip = {'Limpa o gráfico e o resultado do desvio de Allan para um valor de Tau especificado pelo usuário'};
            app.ClearButton.Text = 'LIMPAR';
            app.AvaliarDesviodeAllanparaumespecficoLabel = uilabel(app.UIFigure);
            app.AvaliarDesviodeAllanparaumespecficoLabel.Tooltip = {'Permite ao usuário verificar o valor do desvio de Allan ("ruído da corrente de balance") em um tempo Tau específico'};
            app.AvaliarDesviodeAllanparaumespecficoLabel.Text = 'Avaliar Desvio de Allan em um tempo Tau específico?';
            app.SwitchSpecificTau = uiswitch(app.UIFigure, 'slider');
            app.SwitchSpecificTau.Items = {'Não', 'Sim'};
            app.SwitchSpecificTau.Tooltip = {''};
            app.SwitchSpecificTau.Value = 'Não';
            app.InformeovalordeTauentre10se100sLabel = uilabel(app.UIFigure);
            app.InformeovalordeTauentre10se100sLabel.Tooltip = {'Os valores aqui inseridos serão arredondados para o inteiro mais próximo'};
            app.InformeovalordeTauentre10se100sLabel.Text = 'Informe o valor de Tau (entre 10 s e 100 s) para o qual o desvio de Allan será avaliado';
            app.SpecificTauEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpecificTauEditField.Tooltip = {'Os valores aqui inseridos serão arredondados para o inteiro mais próximo'};
            app.SpecificTauEditField.Value = 10;
            app.OpesLabel = uilabel(app.UIFigure);
            app.OpesLabel.Text = 'Opções';
            app.ResultSpecificTauLabel = uilabel(app.UIFigure);
            app.ResultSpecificTauLabel.Tooltip = {'Nesta área, é exibido o resultado com o desvio de Allan ("ruído da corrente de balance") estimado no tempo Tau especificado pelo usuário'};
            app.ResultSpecificTauLabel.Text = '';
            app.AvaliarDesviodeAllanparaumespecficoLabel_2 = uilabel(app.UIFigure);
            app.AvaliarDesviodeAllanparaumespecficoLabel_2.Tooltip = {'Permite que o usuário ajuste a escala do eixo y (ruído da corrente de balance expresso em termos de desvio de Allan)'};
            app.AvaliarDesviodeAllanparaumespecficoLabel_2.Text = 'Escala do eixo y';
            app.SwitchYScale = uiswitch(app.UIFigure, 'slider');
            app.SwitchYScale.Items = {'Fixa', 'Automática'};
            app.SwitchYScale.ValueChangedFcn = createCallbackFcn(app, @YScaleMode, true);
            app.SwitchYScale.Tooltip = {'Com a chave na posição "Fixa", a escala vai de 1 nA a 100 nA). Com a chave na posição "Automática", é possível dar um "zoom" no gráfico'};
            app.SwitchYScale.Value = 'Fixa';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end